classdef IRISUAV_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        IRISpathEditFieldLabel          matlab.ui.control.Label
        IRISpathEditField               matlab.ui.control.EditField
        TabGroup2                       matlab.ui.container.TabGroup
        IRIS_UAVprocessTab              matlab.ui.container.Tab
        TabGroup                        matlab.ui.container.TabGroup
        IRISTab                         matlab.ui.container.Tab
        build_graphPanel                matlab.ui.container.Panel
        build_graphButton               matlab.ui.control.StateButton
        seedEditFieldLabel              matlab.ui.control.Label
        seedEditField                   matlab.ui.control.EditField
        NumVerticesEditFieldLabel       matlab.ui.control.Label
        NumVerticesEditField            matlab.ui.control.EditField
        filetowriteEditFieldLabel       matlab.ui.control.Label
        filetowriteEditField            matlab.ui.control.EditField
        search_graphPanel               matlab.ui.container.Panel
        file_to_readEditFieldLabel      matlab.ui.control.Label
        file_to_readEditField           matlab.ui.control.EditField
        initial_pEditFieldLabel         matlab.ui.control.Label
        initial_pEditField              matlab.ui.control.EditField
        initial_epsEditFieldLabel       matlab.ui.control.Label
        initial_epsEditField            matlab.ui.control.EditField
        tightening_rateEditFieldLabel   matlab.ui.control.Label
        tightening_rateEditField        matlab.ui.control.EditField
        methodEditFieldLabel            matlab.ui.control.Label
        methodEditField                 matlab.ui.control.EditField
        file_to_writeEditFieldLabel     matlab.ui.control.Label
        file_to_writeEditField          matlab.ui.control.EditField
        search_graphButton              matlab.ui.control.StateButton
        LocationErrorParameterPanel     matlab.ui.container.Panel
        AccbiasmgEditFieldLabel         matlab.ui.control.Label
        AccbiasmgEditField              matlab.ui.control.NumericEditField
        GyrobiasdeghrLabel              matlab.ui.control.Label
        GyrobiasdeghrEditField          matlab.ui.control.NumericEditField
        AvaragevelocitymsEditFieldLabel  matlab.ui.control.Label
        AvaragevelocitymsEditField      matlab.ui.control.NumericEditField
        MintimeinRZsEditFieldLabel      matlab.ui.control.Label
        MintimeinRZsEditField           matlab.ui.control.NumericEditField
        MaxtimeinRZsEditFieldLabel      matlab.ui.control.Label
        MaxtimeinRZsEditField           matlab.ui.control.NumericEditField
        MultipleFactorEditFieldLabel    matlab.ui.control.Label
        MultipleFactorEditField         matlab.ui.control.NumericEditField
        MonteCarloSearchEditFieldLabel  matlab.ui.control.Label
        MonteCarloSearchEditField       matlab.ui.control.NumericEditField
        RunallprocessButton             matlab.ui.control.Button
        UAVSimulationTab                matlab.ui.container.Tab
        UAVSimulationPanel              matlab.ui.container.Panel
        UAV_SimulationButton            matlab.ui.control.StateButton
        InputIRISfilenameEditFieldLabel  matlab.ui.control.Label
        InputIRISfilenameEditField      matlab.ui.control.EditField
        OutputUAV_SimulationfilenameLabel  matlab.ui.control.Label
        OutputUAV_SimulationfilenameEditField  matlab.ui.control.EditField
        CheckPOIandCollisionTab         matlab.ui.container.Tab
        CheckPOIandCollisionButton      matlab.ui.control.StateButton
        CreateTableResult               matlab.ui.container.Tab
        CreateTableResultButton         matlab.ui.control.Button
        OutputTableresultfoldernameEditFieldLabel  matlab.ui.control.Label
        OutputTableresultfoldernameEditField  matlab.ui.control.EditField
        EnvelopeRunTab                  matlab.ui.container.Tab
        IRISMonteCarlonumberEditFieldLabel  matlab.ui.control.Label
        IRISMonteCarlonumberEditField   matlab.ui.control.NumericEditField
        SimulationMonteCarlonumberEditFieldLabel  matlab.ui.control.Label
        SimulationMonteCarlonumberEditField  matlab.ui.control.NumericEditField
        MaxtimeallowedinGNSSOutageregionEditFieldLabel  matlab.ui.control.Label
        MaxtimeallowedinGNSSOutageregionEditField  matlab.ui.control.EditField
        RunEnvelopeButton               matlab.ui.control.Button
        PlotEnvelopeResultButton        matlab.ui.control.Button
        PathoftheenveloperesultEditFieldLabel  matlab.ui.control.Label
        PathoftheenveloperesultEditField  matlab.ui.control.EditField
        MintimeallowedinGNSSOutageregionEditFieldLabel  matlab.ui.control.Label
        MintimeallowedinGNSSOutageregionEditField  matlab.ui.control.EditField
        MonteCarloSearchEnvelopeEditFieldLabel  matlab.ui.control.Label
        MonteCarloSearchEnvelopeEditField  matlab.ui.control.EditField
        pEnvelopeEditFieldLabel         matlab.ui.control.Label
        pEnvelopeEditField              matlab.ui.control.EditField
        NumVerticesEnvelopeEditFieldLabel  matlab.ui.control.Label
        NumVerticesEnvelopeEditField    matlab.ui.control.EditField
        epsEnvelopeEditFieldLabel       matlab.ui.control.Label
        epsEnvelopeEditField            matlab.ui.control.EditField
        SaveAppConfigurationButton      matlab.ui.control.Button
        AppconfigurationFileNameEditFieldLabel  matlab.ui.control.Label
        AppconfigurationFileNameEditField  matlab.ui.control.EditField
    end

    
    properties (Access = private)
        Property % Description
        CurrentFoler;
        counter;
    end
    
    methods (Access = private)
        
        function results = readEnvelopeInput(app,str)
            input = str;
            a= split(input,',');
            values = 1;
            indexValue = 0;
            for i=1:1:length(a)
                b = split(a{i},':');
                for j=1:1:length(b)
                    if length(strfind(a{i},':')) ==2
                        initValue = str2double(b{1});
                        jumpValue = str2double(b{2});
                        finalValue = str2double(b{3});
                        temp = [initValue:jumpValue:finalValue];
                        if(indexValue>0)
                            values = [values,temp];
                        else
                            values = [temp];
                        end
                        indexValue = indexValue+length(temp);
                        
                        break;
                    else
                        indexValue=indexValue+1;
                        values(indexValue) = str2double(b{j});
                    end
                end
            end
            results =values
            
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: search_graphButton
        function search_graphButtonValueChanged(app, event)
            value = app.search_graphButton.Value;
            IRIS_build_folder=app.IRISpathEditField.Value
            CommandToPowershell = ['powershell cd ',IRIS_build_folder,'; wsl '];
            file_to_read = ['Results/',app.OutputTableresultfoldernameEditField.Value,'/Run',num2str(app.counter),'/',app.file_to_readEditField.Value];
            initial_p = app.initial_pEditField.Value;
            initial_eps = app.initial_epsEditField.Value
            tightening_rate = app.tightening_rateEditField.Value;
            method= app.methodEditField.Value;
            
            file_to_write = ['Results/',app.OutputTableresultfoldernameEditField.Value,'/Run',num2str(app.counter),'/',app.file_to_writeEditField.Value];
            Location_error_parameter_file = ['Results/',app.OutputTableresultfoldernameEditField.Value,'/Run',num2str(app.counter),'/','LocationErrorParameterFile'];
            LocationErrorParameterFileName = 'LocationErrorParameterFile';
            
            if ~exist([IRIS_build_folder,'/','Results/',app.OutputTableresultfoldernameEditField.Value,'/Run',num2str(app.counter),'/',LocationErrorParameterFileName])
                LocationErrorParameter = [...
                    app.AccbiasmgEditField.Value,...
                    app.GyrobiasdeghrEditField.Value,...
                    app.AvaragevelocitymsEditField.Value,...
                    app.MintimeinRZsEditField.Value,...
                    app.MaxtimeinRZsEditField.Value,...
                    app.MultipleFactorEditField.Value,...
                    app.MonteCarloSearchEditField.Value,...
                    0];
                %10,10,1,MintimeallowedinGNSSOutageregionEnvelope(i),40,];
                
                WriteLocationErrorParameterFile([IRIS_build_folder,'/','Results/',app.OutputTableresultfoldernameEditField.Value,'/Run',num2str(app.counter),'/',LocationErrorParameterFileName],LocationErrorParameter);%
            end
            
            %             Location_error_parameter_file = 'LocationErrorParameterFile';
            search_graph_input = ['./app/search_graph ',file_to_read,' ',num2str(initial_p),' ',num2str(initial_eps),' ',num2str(tightening_rate),' ',num2str(method),' ',num2str(0),' ',num2str(1),' ',file_to_write,' ',Location_error_parameter_file,' ',app.seedEditField.Value];
            pause(5)
            system([CommandToPowershell,search_graph_input])
        end

        % Value changed function: UAV_SimulationButton
        function UAV_SimulationButtonValueChanged(app, event)
            value = app.UAV_SimulationButton.Value;
            file_to_write = app.InputIRISfilenameEditField.Value;
            IRIS_build_folder=app.IRISpathEditField.Value;
            
            OutputFolderName = [IRIS_build_folder,'/','Results/',app.OutputTableresultfoldernameEditField.Value,'/Run',num2str(app.counter),'/'];
            OutputFileName = ['Results/',app.OutputTableresultfoldernameEditField.Value,'/Run',num2str(app.counter),'/',app.OutputUAV_SimulationfilenameEditField.Value];
            mainUAVsimulation;
        end

        % Value changed function: CheckPOIandCollisionButton
        function CheckPOIandCollisionButtonValueChanged(app, event)
            value = app.CheckPOIandCollisionButton.Value;
            
            resultUAVSimulationFile = ['Results/',app.OutputTableresultfoldernameEditField.Value,'/Run',num2str(app.counter),'/',app.OutputUAV_SimulationfilenameEditField.Value,'_',app.InputIRISfilenameEditField.Value];
            seed =app.seedEditField.Value;
            
            IRIS_build_folder=app.IRISpathEditField.Value;
            CommandToPowershell = ['powershell cd ',IRIS_build_folder,'; wsl '];
            
            system([CommandToPowershell,'./app/checkPOIandCollision ',resultUAVSimulationFile,' ',seed,' ',num2str(1)])
            
        end

        % Value changed function: build_graphButton
        function build_graphButtonValueChanged(app, event)
            value = app.build_graphButton.Value;
            IRIS_build_folder=app.IRISpathEditField.Value;
            CommandToPowershell = ['powershell cd ',IRIS_build_folder,'; wsl '];
            
            MonteCarloRunIRIS = 1;
            if (isempty(app.counter))
                app.counter = 0;
            end
            file_to_write = ['Results/',app.OutputTableresultfoldernameEditField.Value,'/Run',num2str(app.counter),'/',app.filetowriteEditField.Value];
            mkdircommand = ['mkdir -p  ','Results/',app.OutputTableresultfoldernameEditField.Value,'/Run',num2str(app.counter)];
            system([CommandToPowershell,mkdircommand])
            
            num_vertex = app.NumVerticesEditField.Value;
            seed =app.seedEditField.Value;
            
            build_graph_command = ['make','; wsl ','./app/build_graph ',num2str(seed),' ',num2str(num_vertex),' ',file_to_write];
            system([CommandToPowershell,build_graph_command])
        end

        % Button pushed function: CreateTableResultButton
        function CreateTableResultButtonPushed(app, event)
            IRIS_build_folder=app.IRISpathEditField.Value;
            %OutputFileName = ['Results/',app.OutputTableresultfoldernameEditField.Value,'/Run',num2str(app.counter),'/',app.OutputUAV_SimulationfilenameEditField.Value];
            
            OutputFileName = ['Results/',app.OutputTableresultfoldernameEditField.Value,'/Run',num2str(app.counter),'/',app.OutputUAV_SimulationfilenameEditField.Value,'_',app.InputIRISfilenameEditField.Value];
            OutputFolderName = [IRIS_build_folder,'/','Results/',app.OutputTableresultfoldernameEditField.Value,'/Run',num2str(app.counter),'/'];
            
            load([OutputFolderName,'UAVSimulationResults']);
            num_vertex = str2double(app.NumVerticesEditField.Value);
            seed =str2double(app.seedEditField.Value);
            initial_p = str2double(app.initial_pEditField.Value);
            initial_eps = str2double(app.initial_epsEditField.Value);
            tightening_rate = str2double(app.tightening_rateEditField.Value);
            method= str2double(app.methodEditField.Value);
            ResultFolder = [pwd,'\Results\'];
            ResultFolder = [ResultFolder,app.OutputTableresultfoldernameEditField.Value];
            %             a =datestr((now));
            %             OutputFolderTemp = [ResultFolder,a(1:11),'_'];
            %             temp = split(a(13:end),':');
            %             OutputFolder = [OutputFolderTemp,temp{1},temp{2},temp{3}];
            
            
            OutputFolder = ResultFolder;
            %             if ~exist(OutputFolder, 'dir')
            %                 mkdir(OutputFolder)
            %             end
            CurrentFoler = app.CurrentFoler;
            %             load('UAVSimulationResults')
            CreateTableResults;
            if ~isempty(app.CurrentFoler)
                app.CurrentFoler= [];
            end
        end

        % Button pushed function: RunallprocessButton
        function RunallprocessButtonPushed(app, event)
            app.counter = 0;
            IRIS_build_folder=app.IRISpathEditField.Value;
            CommandToPowershell = ['powershell cd ',IRIS_build_folder,'; wsl '];
            mkdircommand = ['mkdir -p ','Results/',app.OutputTableresultfoldernameEditField.Value,'/Run',num2str(app.counter)];
            system([CommandToPowershell,mkdircommand])
            %build_graph
            build_graphButtonValueChanged(app, event);
            %search_graph
            search_graphButtonValueChanged(app, event);
            %uav simualtion
            UAV_SimulationButtonValueChanged(app, event);
            %checkPOIandCollision
            CheckPOIandCollisionButtonValueChanged(app, event);
            %Create table results
            app.CurrentFoler= [];
            CreateTableResultButtonPushed(app, event);
        end

        % Button pushed function: RunEnvelopeButton
        function RunEnvelopeButtonPushed(app, event)
            MintimeallowedinGNSSOutageregionEnvelope = readEnvelopeInput(app,app.MintimeallowedinGNSSOutageregionEditField.Value);
            MaxtimeallowedinGNSSOutageregionEnvelope = readEnvelopeInput(app,app.MaxtimeallowedinGNSSOutageregionEditField.Value);
            MonteCarloSearchEnvelope = readEnvelopeInput(app,app.MonteCarloSearchEnvelopeEditField.Value);
            pEnvelope = readEnvelopeInput(app,app.pEnvelopeEditField.Value);
            epsEnvelope = readEnvelopeInput(app,app.epsEnvelopeEditField.Value);
            NumVerticesEnvelope = readEnvelopeInput(app,app.NumVerticesEnvelopeEditField.Value);
            IRIS_build_folder=app.IRISpathEditField.Value;
            LocationErrorParameterFileName = 'LocationErrorParameterFile';
            %Run build_graph


            app.counter =0;
            for i_NumVertices=1:1:length(NumVerticesEnvelope)
                is_Graph_build = 0;
                nosolution = 0;
                %                 pathBuildGraphFolder = [IRIS_build_folder,'/','Results/',app.OutputTableresultfoldernameEditField.Value,'/Run',num2str(0)];
                % %
                %                 if exist(pathBuildGraphFolder, 'dir')
                %                     rmdir(pathBuildGraphFolder,'s');
                %                 end
                %
                %
                
                for i_p=1:1:length(pEnvelope)
                    if (nosolution)
                        break;
                    end
                    lb_des = (pEnvelope(i_p));
                    [~,MC_init_ref] = findKappaGiven_lb_and_m(lb_des,10,0.05,1)
                    MonteCarloSearchEnvelope = MC_init_ref:20:500;
                    if (MC_init_ref>500)
                        continue;
                    end
                    for i_eps=1:1:length(epsEnvelope)
                        if (nosolution)
                            break;
                        end

                        for j=1:1:(app.IRISMonteCarlonumberEditField.Value)
                            if (nosolution)
                                break;
                            end
                            
                     
                            app.seedEditField.Value = num2str(13*(j+2));
                            for MC_search_id =1:1:length(MonteCarloSearchEnvelope)
                                if (nosolution)
                                    break;
                                end
                                for i=1:1:length(MintimeallowedinGNSSOutageregionEnvelope)
                                    if (nosolution)
                                        break;
                                    end
                                    for i_max=1:1:length(MaxtimeallowedinGNSSOutageregionEnvelope)
                                        %                             app.initial_epsEditField.Value = num2str(epsEnvelope(i_eps));
                                        %                             app.initial_pEditField.Value = num2str(epsEnvelope(i_eps));
                                        app.initial_pEditField.Value = num2str(pEnvelope(i_p));
                                        app.initial_epsEditField.Value = num2str(epsEnvelope(i_eps));
                                        app.NumVerticesEditField.Value = num2str(NumVerticesEnvelope(i_NumVertices));
                                        
                                       
                                        [kappa,MC_init] = findKappaGiven_lb_and_m(pEnvelope(i_p),MonteCarloSearchEnvelope(MC_search_id),0.05,0);
                                            app.initial_pEditField.Value = num2str(kappa);
                                        
%                                         if (MonteCarloSearchEnvelope(MC_search_id)<(MC_init_ref+0.5))
%                                             app.initial_pEditField.Value=num2str(1);
%                                         else
%                                             
%                                             [kappa,MC_init] = findKappaGiven_lb_and_m(pEnvelope(i_p),MonteCarloSearchEnvelope(MC_search_id),0.05,0);
%                                             app.initial_pEditField.Value = num2str(kappa);
%                                         end
%                                         
                                        
                                        app.counter = app.counter+1;
                                        IRIS_build_folder=app.IRISpathEditField.Value;
                                        CommandToPowershell = ['powershell cd ',IRIS_build_folder,'; wsl '];
                                        mkdircommand = ['mkdir -p  ','Results/',app.OutputTableresultfoldernameEditField.Value,'/Run',num2str(app.counter)];
                                        system([CommandToPowershell,mkdircommand])
                                        %Location Error Parameter
                                        LocationErrorParameter = [...
                                            app.AccbiasmgEditField.Value,...
                                            app.GyrobiasdeghrEditField.Value,...
                                            app.AvaragevelocitymsEditField.Value,...
                                            MintimeallowedinGNSSOutageregionEnvelope(i),...%app.MintimeinRZsEditField.Value,...
                                            MaxtimeallowedinGNSSOutageregionEnvelope(i_max),...%app.MaxtimeinRZsEditField.Value,...
                                            app.MultipleFactorEditField.Value,...
                                            MonteCarloSearchEnvelope(MC_search_id),...
                                            0];
                                        %                                         0.05];
                                        %10,10,1,MintimeallowedinGNSSOutageregionEnvelope(i),40,];
                                        
                                        WriteLocationErrorParameterFile([IRIS_build_folder,'/','Results/',app.OutputTableresultfoldernameEditField.Value,'/Run',num2str(app.counter),'/',LocationErrorParameterFileName],LocationErrorParameter);%
                                        %                             app.seedEditField.Value = num2str(i);
                                        
                                        CurrentOutputFolder = ['\runNumber',num2str(i)];
                                        ResultFolder = [pwd,'\Results\'];
                                        OutputFolder = [ResultFolder,app.OutputTableresultfoldernameEditField.Value];
                                        pathBuildGraphFolder = [IRIS_build_folder,'/','Results/',app.OutputTableresultfoldernameEditField.Value,'/Run',num2str(0)];
                                        %                     if ~exist([OutputFolder,CurrentOutputFolder], 'dir')
                                        %                         mkdir([OutputFolder,CurrentOutputFolder])
                                        %                     end
                                        app.CurrentFoler = CurrentOutputFolder;
                                        if (~is_Graph_build)
                                            
                                            pathBuildGraphFolder = [IRIS_build_folder,'/','Results/',app.OutputTableresultfoldernameEditField.Value,'/Run',num2str(0)];
                                            %                         if ~isfile([pathBuildGraphFolder,'/',app.filetowriteEditField.Value,'_conf']) %build was not performed in another thread
                                            
                                            if (~isfile([pathBuildGraphFolder,'/CheckBuildStarted']))
                                                if ~exist(pathBuildGraphFolder, 'dir')
                                                    mkdir(pathBuildGraphFolder);
                                                end
                                                fileID = fopen([pathBuildGraphFolder,'/CheckBuildStarted'],'w');
                                                fclose(fileID);
                                                temp_counter = app.counter;
                                                app.counter = 0;
                                                build_graphButtonValueChanged(app, event);
                                                %                                 app.counter = length(MaxtimeallowedinGNSSOutageregionEnvelope)*(i-1)+i_max;
                                                if (temp_counter==0)
                                                    app.counter =1;
                                                else
                                                    app.counter = temp_counter;
                                                end
                                                
                                                fileID = fopen([pathBuildGraphFolder,'/CheckBuildEnded'],'w');
                                                fclose(fileID);
                                            else
                                                while (~isfile([pathBuildGraphFolder,'/CheckBuildEnded'])) % search was not ended in the first thread
                                                    pause(3);
                                                end
                                            end
                                            
                                            %                         build_graphButtonValueChanged(app, event);
                                            is_Graph_build = 1;
                                        end
                                        %                  if (counter>2)
                                        %                             break;
                                        %                         end
                                        
                                        %build_graph
                                        %                     build_graphButtonValueChanged(app, event);
                                        %search_graph
                                        pathBuildGraphFolder = [IRIS_build_folder,'/','Results/',app.OutputTableresultfoldernameEditField.Value,'/Run',num2str(0)];
                                        pathCurrentFolder = [IRIS_build_folder,'/','Results/',app.OutputTableresultfoldernameEditField.Value,'/Run',num2str(app.counter)];
                                        tryNotSuceess = 1;
                                        while(tryNotSuceess)
                                            try
                                                copyfile([pathBuildGraphFolder,'/',app.filetowriteEditField.Value,'_conf'],[pathCurrentFolder,'/']);
                                                copyfile([pathBuildGraphFolder,'/',app.filetowriteEditField.Value,'_edge'],[pathCurrentFolder,'/']);
                                                copyfile([pathBuildGraphFolder,'/',app.filetowriteEditField.Value,'_vertex'],[pathCurrentFolder,'/']);
                                                %                                 copyfile([pathBuildGraphFolder,'/',app.filetowriteEditField.Value,'_poiVertex'],[pathCurrentFolder,'/']);
                                                
                                                tryNotSuceess = 0;
                                            catch
                                                pause(1);
                                            end
                                        end
                                        %                             copyfile([IRIS_build_folder,'\',app.file_to_writeEditField.Value],[OutputFolder,CurrentOutputFolder,'\IRISOutput\'])
                                        %                             copyfile([IRIS_build_folder,'\',app.file_to_writeEditField.Value,'_result'],[OutputFolder,CurrentOutputFolder,'\IRISOutput\'])
                                        if ~isfile([pathCurrentFolder,'/CheckSearchStarted']) %search was not performed in another thread
                                            if ~exist(pathCurrentFolder, 'dir')
                                                mkdir(pathCurrentFolder);
                                            end
                                            fileID = fopen([pathCurrentFolder,'/CheckSearchStarted'],'w');
                                            fclose(fileID);
                                            search_graphButtonValueChanged(app, event);
                                            
                                            temp = importdata([pathCurrentFolder,'/testIRIS']);
                                            if isempty(temp)%no solution
                                                continue;
                                            end
                                            %                                             nosolution = (temp(end,5)+1e-3 < temp(end,2)*temp(end,3)); %noSolution
                                            %                                             if (nosolution)
                                            %                                                 rmdir(pathCurrentFolder,'s');
                                            %                                                 app.counter = app.counter-1;
                                            %                                                 break;
                                            %                                             end
                                            fileID = fopen([pathCurrentFolder,'/CheckSearchEnded'],'w');
                                            fclose(fileID);
                                        else
                                            continue;
                                            %                             while (~isfile([pathCurrentFolder,'/CheckSearchEnded'])) % search was not ended in the first thread
                                            %                                 pause(3);
                                            %                             end
                                        end
                                        
                                        
                                        %                         if (j==1)
                                        %                             mkdir([OutputFolder,CurrentOutputFolder,'\IRISOutput\'])
                                        %                             copyfile([IRIS_build_folder,'\',LocationErrorParameterFileName],[OutputFolder,CurrentOutputFolder,'\IRISOutput\'])
                                        %                             copyfile([IRIS_build_folder,'\',app.file_to_writeEditField.Value],[OutputFolder,CurrentOutputFolder,'\IRISOutput\'])
                                        %                             copyfile([IRIS_build_folder,'\',app.file_to_writeEditField.Value,'_result'],[OutputFolder,CurrentOutputFolder,'\IRISOutput\'])
                                        %                             copyfile([IRIS_build_folder,'\',app.filetowriteEditField.Value,'_conf'],[OutputFolder,CurrentOutputFolder,'\IRISOutput\'])
                                        %                             copyfile([IRIS_build_folder,'\',app.filetowriteEditField.Value,'_edge'],[OutputFolder,CurrentOutputFolder,'\IRISOutput\'])
                                        %                             copyfile([IRIS_build_folder,'\',app.filetowriteEditField.Value,'_vertex'],[OutputFolder,CurrentOutputFolder,'\IRISOutput\'])
                                        %
                                        %                         end


                                        resultUAVSimulationFile = ['Results/',app.OutputTableresultfoldernameEditField.Value,'/Run',num2str(app.counter),'/',app.InputIRISfilenameEditField.Value];
                                        seed =app.seedEditField.Value;
                                        
                                        IRIS_build_folder=app.IRISpathEditField.Value;
                                        CommandToPowershell = ['powershell cd ',IRIS_build_folder,'; wsl '];
                                        
%                                         system([CommandToPowershell,'./app/checkPOIandCollision ',resultUAVSimulationFile,' ',seed,' ',num2str(app.SimulationMonteCarlonumberEditField.Value)])
                                        
                                        for k=1:1:(app.SimulationMonteCarlonumberEditField.Value)
                                            %uav simualtion
                                            
                                            file_to_write = app.InputIRISfilenameEditField.Value;
                                            %
                                            UAV_SimulationButtonValueChanged(app, event);
                                            %checkPOIandCollision
                                            CheckPOIandCollisionButtonValueChanged(app, event);
                                            %Create table results
                                            app.CurrentFoler = CurrentOutputFolder;
                                            
                                        end
                                    end
                                    
                                end
                                
                            end
                        end
                    end
                end
            end
        end

        % Button pushed function: PlotEnvelopeResultButton
        function PlotEnvelopeResultButtonPushed(app, event)
            folderDir = app.PathoftheenveloperesultEditField.Value;
            PostProcessResultFromFolder;
        end

        % Button pushed function: SaveAppConfigurationButton
        function SaveAppConfigurationButtonPushed(app, event)
            % fn = fieldnames( app);
            UI.app.UIFigure                                           =app.UIFigure
            S.app.IRISpathEditFieldLabel                             =app.IRISpathEditFieldLabel
            S.app.IRISpathEditField                                  =app.IRISpathEditField
            S.app.TabGroup2                                          =app.TabGroup2
            S.app.IRIS_UAVprocessTab                                 =app.IRIS_UAVprocessTab
            S.app.TabGroup                                           =app.TabGroup
            S.app.IRISTab                                            =app.IRISTab
            S.app.build_graphPanel                                   =app.build_graphPanel
            S.app.build_graphButton                                  =app.build_graphButton
            S.app.seedEditFieldLabel                                 =app.seedEditFieldLabel
            S.app.seedEditField                                      =app.seedEditField
            S.app.NumVerticesEditFieldLabel                            =app.NumVerticesEditFieldLabel
            S.app.NumVerticesEditField                                 =app.NumVerticesEditField
            S.app.filetowriteEditFieldLabel                          =app.filetowriteEditFieldLabel
            S.app.filetowriteEditField                               =app.filetowriteEditField
            S.app.search_graphPanel                                  =app.search_graphPanel
            S.app.file_to_readEditFieldLabel                         =app.file_to_readEditFieldLabel
            S.app.file_to_readEditField                              =app.file_to_readEditField
            S.app.initial_pEditFieldLabel                            =app.initial_pEditFieldLabel
            S.app.initial_pEditField                                 =app.initial_pEditField
            S.app.initial_epsEditFieldLabel                          =app.initial_epsEditFieldLabel
            S.app.initial_epsEditField                               =app.initial_epsEditField
            S.app.tightening_rateEditFieldLabel                      =app.tightening_rateEditFieldLabel
            S.app.tightening_rateEditField                           =app.tightening_rateEditField
            S.app.methodEditFieldLabel                               =app.methodEditFieldLabel
            S.app.methodEditField                                    =app.methodEditField
            S.app.file_to_writeEditFieldLabel                        =app.file_to_writeEditFieldLabel
            S.app.file_to_writeEditField                             =app.file_to_writeEditField
            S.app.search_graphButton                                 =app.search_graphButton
            S.app.LocationErrorParameterPanel                        =app.LocationErrorParameterPanel
            S.app.AccbiasmgEditFieldLabel                            =app.AccbiasmgEditFieldLabel
            S.app.AccbiasmgEditField                                 =app.AccbiasmgEditField
            S.app.GyrobiasdeghrLabel                                 =app.GyrobiasdeghrLabel
            S.app.GyrobiasdeghrEditField                             =app.GyrobiasdeghrEditField
            S.app.AvaragevelocitymsEditFieldLabel                    =app.AvaragevelocitymsEditFieldLabel
            S.app.AvaragevelocitymsEditField                         =app.AvaragevelocitymsEditField
            S.app.MintimeinRZsEditFieldLabel                         =app.MintimeinRZsEditFieldLabel
            S.app.MintimeinRZsEditField                              =app.MintimeinRZsEditField
            S.app.MaxtimeinRZsEditFieldLabel                         =app.MaxtimeinRZsEditFieldLabel
            S.app.MaxtimeinRZsEditField                              =app.MaxtimeinRZsEditField
            S.app.MultipleFactorEditFieldLabel                       =app.MultipleFactorEditFieldLabel
            S.app.MultipleFactorEditField                            =app.MultipleFactorEditField
            S.app.RunallprocessButton                                =app.RunallprocessButton
            S.app.UAVSimulationTab                                   =app.UAVSimulationTab
            S.app.UAVSimulationPanel                                 =app.UAVSimulationPanel
            S.app.UAV_SimulationButton                               =app.UAV_SimulationButton
            S.app.InputIRISfilenameEditFieldLabel                    =app.InputIRISfilenameEditFieldLabel
            S.app.InputIRISfilenameEditField                         =app.InputIRISfilenameEditField
            S.app.OutputUAV_SimulationfilenameLabel                  =app.OutputUAV_SimulationfilenameLabel
            S.app.OutputUAV_SimulationfilenameEditField              =app.OutputUAV_SimulationfilenameEditField
            S.app.CheckPOIandCollisionTab                            =app.CheckPOIandCollisionTab
            S.app.CheckPOIandCollisionButton                         =app.CheckPOIandCollisionButton
            S.app.CreateTableResult                                  =app.CreateTableResult
            S.app.CreateTableResultButton                            =app.CreateTableResultButton
            S.app.OutputTableresultfoldernameEditFieldLabel          =app.OutputTableresultfoldernameEditFieldLabel
            S.app.OutputTableresultfoldernameEditField               =app.OutputTableresultfoldernameEditField
            S.app.EnvelopeRunTab                                     =app.EnvelopeRunTab
            S.app.IRISMonteCarlonumberEditFieldLabel                 =app.IRISMonteCarlonumberEditFieldLabel
            S.app.IRISMonteCarlonumberEditField                      =app.IRISMonteCarlonumberEditField
            S.app.SimulationMonteCarlonumberEditFieldLabel           =app.SimulationMonteCarlonumberEditFieldLabel
            S.app.SimulationMonteCarlonumberEditField                =app.SimulationMonteCarlonumberEditField
            S.app.MaxtimeallowedinGNSSOutageregionEditFieldLabel     =app.MaxtimeallowedinGNSSOutageregionEditFieldLabel
            S.app.MaxtimeallowedinGNSSOutageregionEditField          =app.MaxtimeallowedinGNSSOutageregionEditField
            S.app.RunEnvelopeButton                                  =app.RunEnvelopeButton
            S.app.PlotEnvelopeResultButton                           =app.PlotEnvelopeResultButton
            S.app.PathoftheenveloperesultEditFieldLabel              =app.PathoftheenveloperesultEditFieldLabel
            S.app.PathoftheenveloperesultEditField                   =app.PathoftheenveloperesultEditField
            S.app.MintimeallowedinGNSSOutageregionEditFieldLabel     =app.MintimeallowedinGNSSOutageregionEditFieldLabel
            S.app.MintimeallowedinGNSSOutageregionEditField          =app.MintimeallowedinGNSSOutageregionEditField
            
            S.app.SaveAppConfigurationButton     =app.SaveAppConfigurationButton;
            S.app.AppconfigurationFileNameEditFieldLabel   = app.AppconfigurationFileNameEditFieldLabel;
            S.app.AppconfigurationFileNameEditField  = app.AppconfigurationFileNameEditField;
            S.app.MonteCarloSearchEnvelopeEditField = app.MonteCarloSearchEnvelopeEditField;
            S.app.MonteCarloSearchEditField = app.MonteCarloSearchEditField;
            S.event.Source = event.Source;
            S.event.EventName = event.EventName;
            
            save(['Configuration/',app.AppconfigurationFileNameEditField.Value],'S','UI')
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IRISpathEditFieldLabel = uilabel(app.UIFigure);
            app.IRISpathEditFieldLabel.Text = 'IRIS path';
            app.IRISpathEditField = uieditfield(app.UIFigure, 'text');
            app.IRISpathEditField.Value = '\\wsl$\Ubuntu-20.04\home\davidalpert\Projects\IRIS\build';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.IRIS_UAVprocessTab = uitab(app.TabGroup2);
            app.IRIS_UAVprocessTab.Title = 'IRIS_UAV process';
            app.TabGroup = uitabgroup(app.IRIS_UAVprocessTab);
            app.IRISTab = uitab(app.TabGroup);
            app.IRISTab.Title = 'IRIS';
            app.build_graphPanel = uipanel(app.IRISTab);
            app.build_graphPanel.Title = 'build_graph';
            app.build_graphButton = uibutton(app.build_graphPanel, 'state');
            app.build_graphButton.ValueChangedFcn = createCallbackFcn(app, @build_graphButtonValueChanged, true);
            app.build_graphButton.Text = 'build_graph';
            app.seedEditFieldLabel = uilabel(app.build_graphPanel);
            app.seedEditFieldLabel.Text = 'seed';
            app.seedEditField = uieditfield(app.build_graphPanel, 'text');
            app.seedEditField.Value = '1';
            app.NumVerticesEditFieldLabel = uilabel(app.build_graphPanel);
            app.NumVerticesEditFieldLabel.Text = 'NumVertices';
            app.NumVerticesEditField = uieditfield(app.build_graphPanel, 'text');
            app.NumVerticesEditField.Value = '100';
            app.filetowriteEditFieldLabel = uilabel(app.build_graphPanel);
            app.filetowriteEditFieldLabel.Text = 'file to write';
            app.filetowriteEditField = uieditfield(app.build_graphPanel, 'text');
            app.filetowriteEditField.Value = 'testIRIS';
            app.search_graphPanel = uipanel(app.IRISTab);
            app.search_graphPanel.Title = 'search_graph';
            app.file_to_readEditFieldLabel = uilabel(app.search_graphPanel);
            app.file_to_readEditFieldLabel.Text = 'file_to_read';
            app.file_to_readEditField = uieditfield(app.search_graphPanel, 'text');
            app.file_to_readEditField.Value = 'testIRIS';
            app.initial_pEditFieldLabel = uilabel(app.search_graphPanel);
            app.initial_pEditFieldLabel.Text = 'initial_p';
            app.initial_pEditField = uieditfield(app.search_graphPanel, 'text');
            app.initial_pEditField.Value = '1';
            app.initial_epsEditFieldLabel = uilabel(app.search_graphPanel);
            app.initial_epsEditFieldLabel.Text = 'initial_eps';
            app.initial_epsEditField = uieditfield(app.search_graphPanel, 'text');
            app.initial_epsEditField.Value = '10';
            app.tightening_rateEditFieldLabel = uilabel(app.search_graphPanel);
            app.tightening_rateEditFieldLabel.Text = 'tightening_rate';
            app.tightening_rateEditField = uieditfield(app.search_graphPanel, 'text');
            app.tightening_rateEditField.Value = '0';
            app.methodEditFieldLabel = uilabel(app.search_graphPanel);
            app.methodEditFieldLabel.Text = 'method';
            app.methodEditField = uieditfield(app.search_graphPanel, 'text');
            app.methodEditField.Value = '0';
            app.file_to_writeEditFieldLabel = uilabel(app.search_graphPanel);
            app.file_to_writeEditFieldLabel.Text = 'file_to_write';
            app.file_to_writeEditField = uieditfield(app.search_graphPanel, 'text');
            app.file_to_writeEditField.Value = 'testIRIS';
            app.search_graphButton = uibutton(app.search_graphPanel, 'state');
            app.search_graphButton.ValueChangedFcn = createCallbackFcn(app, @search_graphButtonValueChanged, true);
            app.search_graphButton.Text = 'search_graph';
            app.LocationErrorParameterPanel = uipanel(app.IRISTab);
            app.LocationErrorParameterPanel.Title = 'LocationErrorParameter';
            app.AccbiasmgEditFieldLabel = uilabel(app.LocationErrorParameterPanel);
            app.AccbiasmgEditFieldLabel.Text = 'Acc bias [mg]';
            app.AccbiasmgEditField = uieditfield(app.LocationErrorParameterPanel, 'numeric');
            app.AccbiasmgEditField.Value = 10;
            app.GyrobiasdeghrLabel = uilabel(app.LocationErrorParameterPanel);
            app.GyrobiasdeghrLabel.Text = 'Gyro bias [deg/hr]';
            app.GyrobiasdeghrEditField = uieditfield(app.LocationErrorParameterPanel, 'numeric');
            app.GyrobiasdeghrEditField.Value = 10;
            app.AvaragevelocitymsEditFieldLabel = uilabel(app.LocationErrorParameterPanel);
            app.AvaragevelocitymsEditFieldLabel.Text = 'Avarage velocity [m/s]';
            app.AvaragevelocitymsEditField = uieditfield(app.LocationErrorParameterPanel, 'numeric');
            app.AvaragevelocitymsEditField.Value = 1;
            app.MintimeinRZsEditFieldLabel = uilabel(app.LocationErrorParameterPanel);
            app.MintimeinRZsEditFieldLabel.Text = 'Min time in RZ [s]';
            app.MintimeinRZsEditField = uieditfield(app.LocationErrorParameterPanel, 'numeric');
            app.MaxtimeinRZsEditFieldLabel = uilabel(app.LocationErrorParameterPanel);
            app.MaxtimeinRZsEditFieldLabel.Text = 'Max time in RZ [s]';
            app.MaxtimeinRZsEditField = uieditfield(app.LocationErrorParameterPanel, 'numeric');
            app.MaxtimeinRZsEditField.Value = 2000;
            app.MultipleFactorEditFieldLabel = uilabel(app.LocationErrorParameterPanel);
            app.MultipleFactorEditFieldLabel.Text = 'Multiple Factor';
            app.MultipleFactorEditField = uieditfield(app.LocationErrorParameterPanel, 'numeric');
            app.MultipleFactorEditField.Value = 40;
            app.MonteCarloSearchEditFieldLabel = uilabel(app.LocationErrorParameterPanel);
            app.MonteCarloSearchEditFieldLabel.Text = 'Monte Carlo Search';
            app.MonteCarloSearchEditField = uieditfield(app.LocationErrorParameterPanel, 'numeric');
            app.RunallprocessButton = uibutton(app.IRISTab, 'push');
            app.RunallprocessButton.ButtonPushedFcn = createCallbackFcn(app, @RunallprocessButtonPushed, true);
            app.RunallprocessButton.Text = 'Run all process';
            app.UAVSimulationTab = uitab(app.TabGroup);
            app.UAVSimulationTab.Title = 'UAV Simulation';
            app.UAVSimulationPanel = uipanel(app.UAVSimulationTab);
            app.UAVSimulationPanel.Title = 'UAV Simulation';
            app.UAV_SimulationButton = uibutton(app.UAVSimulationPanel, 'state');
            app.UAV_SimulationButton.ValueChangedFcn = createCallbackFcn(app, @UAV_SimulationButtonValueChanged, true);
            app.UAV_SimulationButton.Text = 'UAV_Simulation';
            app.InputIRISfilenameEditFieldLabel = uilabel(app.UAVSimulationPanel);
            app.InputIRISfilenameEditFieldLabel.Text = 'Input : IRIS file name';
            app.InputIRISfilenameEditField = uieditfield(app.UAVSimulationPanel, 'text');
            app.InputIRISfilenameEditField.Value = 'testIRIS';
            app.OutputUAV_SimulationfilenameLabel = uilabel(app.UAVSimulationPanel);
            app.OutputUAV_SimulationfilenameLabel.Text = 'Output : UAV_Simulation file name';
            app.OutputUAV_SimulationfilenameEditField = uieditfield(app.UAVSimulationPanel, 'text');
            app.OutputUAV_SimulationfilenameEditField.Value = 'SimulationPath';
            app.CheckPOIandCollisionTab = uitab(app.TabGroup);
            app.CheckPOIandCollisionTab.Title = 'CheckPOIandCollision';
            app.CheckPOIandCollisionButton = uibutton(app.CheckPOIandCollisionTab, 'state');
            app.CheckPOIandCollisionButton.ValueChangedFcn = createCallbackFcn(app, @CheckPOIandCollisionButtonValueChanged, true);
            app.CheckPOIandCollisionButton.Text = 'Check POIandCollision';
            app.CreateTableResult = uitab(app.TabGroup);
            app.CreateTableResult.Title = 'CreateTableResult';
            app.CreateTableResultButton = uibutton(app.CreateTableResult, 'push');
            app.CreateTableResultButton.ButtonPushedFcn = createCallbackFcn(app, @CreateTableResultButtonPushed, true);
            app.CreateTableResultButton.Text = 'CreateTableResult';
            app.OutputTableresultfoldernameEditFieldLabel = uilabel(app.CreateTableResult);
            app.OutputTableresultfoldernameEditFieldLabel.Text = 'Output : Table result folder name';
            app.OutputTableresultfoldernameEditField = uieditfield(app.CreateTableResult, 'text');
            app.OutputTableresultfoldernameEditField.Value = 'test_MC';
            app.EnvelopeRunTab = uitab(app.TabGroup2);
            app.EnvelopeRunTab.Title = 'Envelope Run';
            app.IRISMonteCarlonumberEditFieldLabel = uilabel(app.EnvelopeRunTab);
            app.IRISMonteCarlonumberEditFieldLabel.Text = 'IRIS Monte Carlo number';
            app.IRISMonteCarlonumberEditField = uieditfield(app.EnvelopeRunTab, 'numeric');
            app.IRISMonteCarlonumberEditField.Value = 1;
            app.SimulationMonteCarlonumberEditFieldLabel = uilabel(app.EnvelopeRunTab);
            app.SimulationMonteCarlonumberEditFieldLabel.Text = 'Simulation Monte Carlo number';
            app.SimulationMonteCarlonumberEditField = uieditfield(app.EnvelopeRunTab, 'numeric');
            app.SimulationMonteCarlonumberEditField.Value = 100;
            app.MaxtimeallowedinGNSSOutageregionEditFieldLabel = uilabel(app.EnvelopeRunTab);
            app.MaxtimeallowedinGNSSOutageregionEditFieldLabel.Text = 'Max time allowed in GNSS-Outage region';
            app.MaxtimeallowedinGNSSOutageregionEditField = uieditfield(app.EnvelopeRunTab, 'text');
            app.MaxtimeallowedinGNSSOutageregionEditField.Value = '10000000';
            app.RunEnvelopeButton = uibutton(app.EnvelopeRunTab, 'push');
            app.RunEnvelopeButton.ButtonPushedFcn = createCallbackFcn(app, @RunEnvelopeButtonPushed, true);
            app.RunEnvelopeButton.Text = 'Run Envelope';
            app.PlotEnvelopeResultButton = uibutton(app.EnvelopeRunTab, 'push');
            app.PlotEnvelopeResultButton.ButtonPushedFcn = createCallbackFcn(app, @PlotEnvelopeResultButtonPushed, true);
            app.PlotEnvelopeResultButton.Text = 'PlotEnvelopeResult';
            app.PathoftheenveloperesultEditFieldLabel = uilabel(app.EnvelopeRunTab);
            app.PathoftheenveloperesultEditFieldLabel.Text = 'Path of the envelope result';
            app.PathoftheenveloperesultEditField = uieditfield(app.EnvelopeRunTab, 'text');
            app.PathoftheenveloperesultEditField.Value = 'C:\Users\david\OneDrive - Technion\Master\UAV_Simulation\Results\Test_13012020';
            app.MintimeallowedinGNSSOutageregionEditFieldLabel = uilabel(app.EnvelopeRunTab);
            app.MintimeallowedinGNSSOutageregionEditFieldLabel.Text = 'Min time allowed in GNSS-Outage region';
            app.MintimeallowedinGNSSOutageregionEditField = uieditfield(app.EnvelopeRunTab, 'text');
            app.MintimeallowedinGNSSOutageregionEditField.Value = '10000000';
            app.MonteCarloSearchEnvelopeEditFieldLabel = uilabel(app.EnvelopeRunTab);
            app.MonteCarloSearchEnvelopeEditFieldLabel.Text = 'Monte Carlo Search Envelope';
            app.MonteCarloSearchEnvelopeEditField = uieditfield(app.EnvelopeRunTab, 'text');
            app.MonteCarloSearchEnvelopeEditField.Value = '0';
            app.pEnvelopeEditFieldLabel = uilabel(app.EnvelopeRunTab);
            app.pEnvelopeEditFieldLabel.Text = 'p Envelope';
            app.pEnvelopeEditField = uieditfield(app.EnvelopeRunTab, 'text');
            app.pEnvelopeEditField.Value = '1';
            app.NumVerticesEnvelopeEditFieldLabel = uilabel(app.EnvelopeRunTab);
            app.NumVerticesEnvelopeEditFieldLabel.Text = 'NumVertices Envelope';
            app.NumVerticesEnvelopeEditField = uieditfield(app.EnvelopeRunTab, 'text');
            app.NumVerticesEnvelopeEditField.Value = '50';
            app.epsEnvelopeEditFieldLabel = uilabel(app.EnvelopeRunTab);
            app.epsEnvelopeEditFieldLabel.Text = 'eps Envelope';
            app.epsEnvelopeEditField = uieditfield(app.EnvelopeRunTab, 'text');
            app.epsEnvelopeEditField.Value = '3';
            app.SaveAppConfigurationButton = uibutton(app.UIFigure, 'push');
            app.SaveAppConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAppConfigurationButtonPushed, true);
            app.SaveAppConfigurationButton.Text = 'Save App Configuration';
            app.AppconfigurationFileNameEditFieldLabel = uilabel(app.UIFigure);
            app.AppconfigurationFileNameEditFieldLabel.Text = 'App configuration File Name';
            app.AppconfigurationFileNameEditField = uieditfield(app.UIFigure, 'text');
            app.AppconfigurationFileNameEditField.Value = 'test_MC';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IRISUAV_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end